classdef assignmentv2base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        BillCalculatorTab     matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        Panel_2               matlab.ui.container.Panel
        ExportSnapshotButton  matlab.ui.control.Button
        CalculateButton       matlab.ui.control.Button
        Panel_5               matlab.ui.container.Panel
        FinalBillLabel        matlab.ui.control.Label
        FinalBillField        matlab.ui.control.NumericEditField
        Panel_4               matlab.ui.container.Panel
        ServiceTaxLabel       matlab.ui.control.Label
        ServiceTaxField       matlab.ui.control.NumericEditField
        Panel_3               matlab.ui.container.Panel
        ConsumptioninRMLabel  matlab.ui.control.Label
        ConsumptioninRMField  matlab.ui.control.NumericEditField
        tpc                   matlab.ui.control.NumericEditField
        TotalPowerConsumptionKwHEditFieldLabel  matlab.ui.control.Label
        TariffRatesTab        matlab.ui.container.Tab
        TarrifTable           matlab.ui.control.Table
        AboutUsTab            matlab.ui.container.Tab
        Label                 matlab.ui.control.Label
        MemberTable           matlab.ui.control.Table
        Panel                 matlab.ui.container.Panel
        FAS1074Group1TNBBillCalculatorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = cell2table({0,0,0,"Consumption (kWh)";
                                           0,0,0,"Consumption (RM)";
                                           0,0,0,"ICPT (-RM0.02 per kWh)";
                                           0,0,0,"ST";
                                           0,0,0,"Current Month Consumption (RM)";
                                           0,0,3,"Current Bill (RM)"
                                          });

            app.FinalBillField.Value = 3;

            app.TarrifTable.Data = cell2table({"Minimum Monthly Charge (0 kWh)", "sen/kWh", 3.0;
                                               "For the first 200 kWh (1 – 200 kWh) per month","sen/kWh",21.80;
                                               "For the next 100 kWh (201 – 300 kWh) per month","sen/kWh",33.40;
                                               "For the next 300 kWh (301 – 600 kWh) per month","sen/kWh",51.60;
                                               "For the next 300 kWh (601 – 900 kWh) per month","sen/kWh",54.60;
                                               "For the next kWh (901 kWh onwards) per month","sen/kWh",57.10;
                                              });

            app.MemberTable.Data = cell2table({"Eyad Mohamed Ali Mohamed", "TBA";
                                               "Youssef Raafat Sobhi Hassan", "TBA";
                                               "Altaf Uddin", "TBA"
                                               "Jomana Ahmed Abdelkader Abdelgilil", "TBA"
                                              });
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            total_power = app.tpc.Value;
            power_broken = tnb_power_split(total_power);
            [tcm, total_icpt, total_st, current_bill] = tnb_get_bill(power_broken);
            consumed_power_na = total_power - 600;
            consumption_money_na = tcm - 231.80;
            icpt_na = total_icpt + 12;

            if consumed_power_na < 0
                consumed_power_na = 0;
                consumed_power = total_power;
                
                consumption_money_na = 0;
                consumption_money = tcm;

                icpt_na = 0;
                icpt = total_icpt;
            else
                consumed_power = 600;
                consumption_money = 231.80;
                icpt = -12.0;
            end

            cmc = consumption_money + icpt;
            cmc_na = consumption_money_na + icpt_na;
            cmc_total = tcm + total_icpt;

            app.UITable.Data = cell2table({consumed_power,consumed_power_na,total_power,"Consumption (kWh)";
                                           consumption_money,consumption_money_na,tcm,"Consumption (RM)";
                                           icpt,icpt_na,total_icpt,"ICPT (-RM0.02 per kWh)";
                                           cmc,cmc_na,cmc_total,"Current Month Consumption (RM)";
                                           nan,nan,total_st,"ST";
                                           nan,nan,current_bill,"Current Bill (RM)"
                                          });
            app.ConsumptioninRMField.Value = tcm;
            app.ServiceTaxField.Value = total_st;
            app.FinalBillField.Value = current_bill;
        end

        % Button pushed function: ExportSnapshotButton
        function ExportSnapshotButtonPushed(app, event)
            exportapp(app.UIFigure, "TNB Receipt.jpg");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.FAS1074Group1TNBBillCalculatorLabel = uilabel(app.Panel);
            app.FAS1074Group1TNBBillCalculatorLabel.Text = 'FAS1074 Group 1 - TNB Bill Calculator';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BillCalculatorTab = uitab(app.TabGroup);
            app.BillCalculatorTab.Title = 'Bill Calculator';
            app.Panel_2 = uipanel(app.BillCalculatorTab);
            app.TotalPowerConsumptionKwHEditFieldLabel = uilabel(app.Panel_2);
            app.TotalPowerConsumptionKwHEditFieldLabel.Text = 'Total Power Consumption (KwH)';
            app.tpc = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.Panel_2);
            app.ConsumptioninRMField = uieditfield(app.Panel_3, 'numeric');
            app.ConsumptioninRMField.Editable = 'off';
            app.ConsumptioninRMLabel = uilabel(app.Panel_3);
            app.ConsumptioninRMLabel.Text = 'Consumption in RM';
            app.Panel_4 = uipanel(app.Panel_2);
            app.ServiceTaxField = uieditfield(app.Panel_4, 'numeric');
            app.ServiceTaxField.Editable = 'off';
            app.ServiceTaxLabel = uilabel(app.Panel_4);
            app.ServiceTaxLabel.Text = 'Service Tax';
            app.Panel_5 = uipanel(app.Panel_2);
            app.FinalBillField = uieditfield(app.Panel_5, 'numeric');
            app.FinalBillField.Editable = 'off';
            app.FinalBillLabel = uilabel(app.Panel_5);
            app.FinalBillLabel.Text = 'Final Bill';
            app.CalculateButton = uibutton(app.Panel_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.ExportSnapshotButton = uibutton(app.Panel_2, 'push');
            app.ExportSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSnapshotButtonPushed, true);
            app.ExportSnapshotButton.Text = 'Export Snapshot';
            app.UITable = uitable(app.BillCalculatorTab);
            app.UITable.ColumnName = {'ST Applicable'; 'ST Not Applicable'; 'Total'; 'Description'};
            app.UITable.RowName = {};
            app.TariffRatesTab = uitab(app.TabGroup);
            app.TariffRatesTab.Title = 'Tariff Rates';
            app.TarrifTable = uitable(app.TariffRatesTab);
            app.TarrifTable.ColumnName = {'Tariff Category'; 'Unit'; 'Current Rate'; ''};
            app.TarrifTable.RowName = {};
            app.AboutUsTab = uitab(app.TabGroup);
            app.AboutUsTab.Title = 'About Us';
            app.MemberTable = uitable(app.AboutUsTab);
            app.MemberTable.ColumnName = {'Member Name'; 'Member ID'; ''; ''};
            app.MemberTable.RowName = {};
            app.Label = uilabel(app.AboutUsTab);
            app.Label.Text = {'This program is an assignment for the Introduction to Engineering (FAS1074) Course at UCSI University, under the supervision of Professor. Rodney'; 'Hope you enjoy the program, in the case of facing any issues regarding the program please post the issue at '; 'https://github.com/eyadali05/tnb-bill-calculator'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignmentv2base

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end